classdef AEDist2017 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AEDist2017Label           matlab.ui.control.Label
        SubjectIDEditFieldLabel   matlab.ui.control.Label
        subjectID                 matlab.ui.control.NumericEditField
        SubjectsexDropDownLabel   matlab.ui.control.Label
        subjectSex                matlab.ui.control.DropDown
        language                  matlab.ui.container.ButtonGroup
        EnglishButton             matlab.ui.control.RadioButton
        CzechButton               matlab.ui.control.RadioButton
        runButton                 matlab.ui.control.Button
        SubjectageEditFieldLabel  matlab.ui.control.Label
        SubjectAge                matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        ID = 0;
        sex = 'Male';
        age = 0;
        lang = 'English';
    end


    methods (Access = private)

        % Button pushed function: runButton
        function runningExperiment(app, event)
            disp('Experiment is starting...')
            if app.ID ~= 0 && app.age ~= 0
                if strcmp(app.lang, 'English')
                    Stereo_English(app.ID, app.sex(1), app.age);
                else
                    Stereo_Czech(app.ID, app.sex(1), app.age);
                end
                app.delete;
            else
                errordlg('You have to enter proper values, e.g. non zero values!');
            end
        end

        % Value changed function: subjectID
        function subjectIDValueChanged(app, event)
            app.ID = app.subjectID.Value;
        end

        % Value changed function: subjectSex
        function subjectSexValueChanged(app, event)
            app.sex = app.subjectSex.Value; 
        end

        % Selection changed function: language
        function languageSelectionChanged(app, event)
            language = app.language.SelectedObject;
            app.lang = language.Text;
        end

        % Value changed function: SubjectAge
        function SubjectAgeValueChanged(app, event)
            app.age = app.SubjectAge.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AEDist2017Label = uilabel(app.UIFigure);
            app.AEDist2017Label.Text = 'AEDist2017';
            app.SubjectIDEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.subjectID = uieditfield(app.UIFigure, 'numeric');
            app.subjectID.ValueChangedFcn = createCallbackFcn(app, @subjectIDValueChanged, true);
            app.SubjectsexDropDownLabel = uilabel(app.UIFigure);
            app.SubjectsexDropDownLabel.Text = 'Subject sex';
            app.subjectSex = uidropdown(app.UIFigure);
            app.subjectSex.Items = {'Male', 'Female'};
            app.subjectSex.ValueChangedFcn = createCallbackFcn(app, @subjectSexValueChanged, true);
            app.subjectSex.Value = 'Male';
            app.language = uibuttongroup(app.UIFigure);
            app.language.SelectionChangedFcn = createCallbackFcn(app, @languageSelectionChanged, true);
            app.language.Title = 'Language version';
            app.EnglishButton = uiradiobutton(app.language);
            app.EnglishButton.Text = 'English';
            app.EnglishButton.Value = true;
            app.CzechButton = uiradiobutton(app.language);
            app.CzechButton.Text = 'Czech';
            app.runButton = uibutton(app.UIFigure, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runningExperiment, true);
            app.runButton.Text = 'Run experiment';
            app.SubjectageEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectageEditFieldLabel.Text = 'Subject age';
            app.SubjectAge = uieditfield(app.UIFigure, 'numeric');
            app.SubjectAge.ValueChangedFcn = createCallbackFcn(app, @SubjectAgeValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = AEDist2017

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end