classdef StereoGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StereoscopicnavigationexperimentLabel  matlab.ui.control.Label
        NotesTextAreaLabel            matlab.ui.control.Label
        NotesTextArea                 matlab.ui.control.TextArea
        SubjectinfomrationPanel       matlab.ui.container.Panel
        HandednessButtonGroup         matlab.ui.container.ButtonGroup
        RightButton                   matlab.ui.control.RadioButton
        LeftButton                    matlab.ui.control.RadioButton
        GenderButtonGroup             matlab.ui.container.ButtonGroup
        MaleButton                    matlab.ui.control.RadioButton
        FemaleButton                  matlab.ui.control.RadioButton
        SubjectIDEditFieldLabel       matlab.ui.control.Label
        SubjectIDEditField            matlab.ui.control.NumericEditField
        AgeEditFieldLabel             matlab.ui.control.Label
        AgeEditField                  matlab.ui.control.NumericEditField
        ExperimentatorEditFieldLabel  matlab.ui.control.Label
        ExperimentatorEditField       matlab.ui.control.EditField
        ExperimentsetupPanel          matlab.ui.container.Panel
        FieldofViewKnobLabel          matlab.ui.control.Label
        FieldofViewKnob               matlab.ui.control.Knob
        StereoscopySwitchLabel        matlab.ui.control.Label
        StereoscopySwitch             matlab.ui.control.Switch
        StartexperimentButton         matlab.ui.control.Button
    end


    properties (Access = public)
        ID;
        gender = 'M';
        age;
        experimentator;
        handedness = 'R';
        fov = 60;
        note;
        stereosopy;
        notes;
        subjectID;
    end


    methods (Access = private)

        % Value changed function: SubjectIDEditField
        function SubjectIDEditFieldValueChanged(app, event)
            app.ID = app.SubjectIDEditField.Value;
        end

        % Button pushed function: StartexperimentButton
        function StartexperimentButtonPushed(app, event)
            app.subjectID = sprintf('S%d_%s%s%d_%s', app.ID, app.gender, app.handedness, app.age, app.experimentator);
            disp(app.subjectID);
            disp(app.fov);
            disp(app.note);
            StereoPilot2(app.fov, app.subjectID, app.note);
        end

        % Selection changed function: HandednessButtonGroup
        function HandednessButtonGroupSelectionChanged(app, event)
            selectedButton = app.HandednessButtonGroup.SelectedObject;
            app.handedness = selectedButton.Text(1);
        end

        % Selection changed function: GenderButtonGroup
        function GenderButtonGroupSelectionChanged(app, event)
            selectedButton = app.GenderButtonGroup.SelectedObject;
            app.gender = selectedButton.Text(1);
        end

        % Value changed function: AgeEditField
        function AgeEditFieldValueChanged(app, event)
            value = app.AgeEditField.Value;
            app.age = value;
        end

        % Value changed function: ExperimentatorEditField
        function ExperimentatorEditFieldValueChanged(app, event)
            value = app.ExperimentatorEditField.Value;
            app.experimentator = value;
        end

        % Value changed function: FieldofViewKnob
        function FieldofViewKnobValueChanged(app, event)
            value = app.FieldofViewKnob.Value;
            app.fov = value;
        end

        % Value changed function: NotesTextArea
        function NotesTextAreaValueChanged(app, event)
            value = app.NotesTextArea.Value;
            app.note = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.StereoscopicnavigationexperimentLabel = uilabel(app.UIFigure);
            app.StereoscopicnavigationexperimentLabel.Text = 'Stereoscopic navigation experiment';
            app.NotesTextAreaLabel = uilabel(app.UIFigure);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.UIFigure);
            app.NotesTextArea.ValueChangedFcn = createCallbackFcn(app, @NotesTextAreaValueChanged, true);
            app.SubjectinfomrationPanel = uipanel(app.UIFigure);
            app.SubjectinfomrationPanel.Title = 'Subject infomration';
            app.HandednessButtonGroup = uibuttongroup(app.SubjectinfomrationPanel);
            app.HandednessButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HandednessButtonGroupSelectionChanged, true);
            app.HandednessButtonGroup.Title = 'Handedness';
            app.RightButton = uiradiobutton(app.HandednessButtonGroup);
            app.RightButton.Text = 'Right';
            app.RightButton.Value = true;
            app.LeftButton = uiradiobutton(app.HandednessButtonGroup);
            app.LeftButton.Text = 'Left';
            app.GenderButtonGroup = uibuttongroup(app.SubjectinfomrationPanel);
            app.GenderButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GenderButtonGroupSelectionChanged, true);
            app.GenderButtonGroup.Title = 'Gender';
            app.MaleButton = uiradiobutton(app.GenderButtonGroup);
            app.MaleButton.Text = 'Male';
            app.MaleButton.Value = true;
            app.FemaleButton = uiradiobutton(app.GenderButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.SubjectIDEditFieldLabel = uilabel(app.SubjectinfomrationPanel);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.SubjectinfomrationPanel, 'numeric');
            app.SubjectIDEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectIDEditFieldValueChanged, true);
            app.AgeEditFieldLabel = uilabel(app.SubjectinfomrationPanel);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.SubjectinfomrationPanel, 'numeric');
            app.AgeEditField.ValueChangedFcn = createCallbackFcn(app, @AgeEditFieldValueChanged, true);
            app.ExperimentatorEditFieldLabel = uilabel(app.SubjectinfomrationPanel);
            app.ExperimentatorEditFieldLabel.Text = 'Experimentator';
            app.ExperimentatorEditField = uieditfield(app.SubjectinfomrationPanel, 'text');
            app.ExperimentatorEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimentatorEditFieldValueChanged, true);
            app.ExperimentsetupPanel = uipanel(app.UIFigure);
            app.ExperimentsetupPanel.Title = 'Experiment setup';
            app.FieldofViewKnobLabel = uilabel(app.ExperimentsetupPanel);
            app.FieldofViewKnobLabel.Text = 'Field of View';
            app.FieldofViewKnob = uiknob(app.ExperimentsetupPanel, 'continuous');
            app.FieldofViewKnob.Limits = [30 90];
            app.FieldofViewKnob.MajorTicks = [30 40 50 60 70 80 90];
            app.FieldofViewKnob.ValueChangedFcn = createCallbackFcn(app, @FieldofViewKnobValueChanged, true);
            app.FieldofViewKnob.Value = 60;
            app.StereoscopySwitchLabel = uilabel(app.ExperimentsetupPanel);
            app.StereoscopySwitchLabel.Text = 'Stereoscopy';
            app.StereoscopySwitch = uiswitch(app.ExperimentsetupPanel, 'slider');
            app.StereoscopySwitch.Value = 'On';
            app.StartexperimentButton = uibutton(app.UIFigure, 'push');
            app.StartexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartexperimentButtonPushed, true);
            app.StartexperimentButton.Text = 'Start experiment';
        end
    end

    methods (Access = public)

        % Construct app
        function app = StereoGUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end